classdef FrameGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        BreakSerialPortButton  matlab.ui.control.Button
        CaptureImageButton     matlab.ui.control.Button
        UIAxes3                matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function BreakSerialPortButtonPushed(app, event)

        end

        % Button pushed function: CaptureImageButton
        function CaptureImageButtonPushed(app, event)
            s = serialport("com7", 19200);
            writeline(s,"f");
            
            F1 = read(s,105627,"uint8");
            F2 = zeros(324, 324, 'uint8');
            F1=F1(3:end-1);
            
            for i=1:324
            
              F1=F1(3:end);         % throw away next 2 pixels
            
              F2(i,:) = F1(1:324);  % extract 324 pixels and put them in 2D array
            
              F1=F1(325:end);       % throw away extracted pixels
            
            end
            I = imrotate(F2,-90);
            imshow(I,'Parent',app.UIAxes2);
            
            I = demosaic(F2, "bggr");
            
            I = imrotate(I, -90);
            
            imshow(I,'Parent',app.UIAxes3);

            clear s;
        end

        % Button pushed function: BreakSerialPortButton
        function BreakSerialPortButtonPushed2(app, event)
            clear s;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'GrayScale Image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Color Image')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.CaptureImageButton = uibutton(app.UIFigure, 'push');
            app.CaptureImageButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureImageButtonPushed, true);
            app.CaptureImageButton.Text = 'Capture Image';
            app.BreakSerialPortButton = uibutton(app.UIFigure, 'push');
            app.BreakSerialPortButton.ButtonPushedFcn = createCallbackFcn(app, @BreakSerialPortButtonPushed2, true);
            app.BreakSerialPortButton.Text = 'Break Serial Port';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FrameGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end